classdef popViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        numberOfGens % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filterspec = {'*.csv'};
            [f, p] = uigetfile(filterspec);
            path = append(p,f)
            app.data = readmatrix(path)
            means = mean(app.data,2);
            medians = median(app.data,2);
            bests = max(app.data,[],2);
            generations = [1:length(means)];
            app.numberOfGens = length(means);
            app.Slider.Limits = [1,app.numberOfGens]
            histogram(app.UIAxes, app.data(1,:),100)
            plot(app.UIAxes2,generations,bests,generations,medians,generations,means,'LineWidth',2);
            legend(app.UIAxes2,'Best', 'Median', 'Mean')
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            

        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = floor(event.Value);
            %value = floor(app.Slider.Value);
            % Plot all histograms with the same data for grayscale
            %title = "Generation" 
            %app.UIAxes.Title = title.append(int2str(changingValue))
            histogram(app.UIAxes, app.data(changingValue,:),100);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fitness Distribution')
            xlabel(app.UIAxes, 'Fitness')
            ylabel(app.UIAxes, 'Creatures')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Best,Mean and Median fitnes per generation')
            xlabel(app.UIAxes2, 'Generations')
            ylabel(app.UIAxes2, 'Fitness')
            zlabel(app.UIAxes2, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = popViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end